classdef AOX_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        uipanel1                 matlab.ui.container.ButtonGroup
        disp_FLAGcheck           matlab.ui.control.CheckBox
        hist_FLAGcheck           matlab.ui.control.CheckBox
        print_FLAGcheck          matlab.ui.control.CheckBox
        res_FLAGcheck            matlab.ui.control.CheckBox
        corr_FLAGcheck           matlab.ui.control.CheckBox
        rescorr_FLAGcheck        matlab.ui.control.CheckBox
        excel_FLAGcheck          matlab.ui.control.CheckBox
        BALFIT_Matrix_FLAGcheck  matlab.ui.control.CheckBox
        BALFIT_ANOVA_FLAGcheck   matlab.ui.control.CheckBox
        Rec_Model_FLAGcheck      matlab.ui.control.CheckBox
        approx_and_PI_print      matlab.ui.control.CheckBox
        text57                   matlab.ui.control.Label
        text58                   matlab.ui.control.Label
        output_location          matlab.ui.control.EditField
        output_location_button   matlab.ui.control.Button
        text59                   matlab.ui.control.Label
        output_to_calib_FLAG     matlab.ui.control.CheckBox
        subfolder_FLAG           matlab.ui.control.CheckBox
        calib_model_save_FLAG    matlab.ui.control.CheckBox
        input_save_FLAG          matlab.ui.control.CheckBox
        modelPanel               matlab.ui.container.ButtonGroup
        truncated                matlab.ui.control.RadioButton
        linear                   matlab.ui.control.RadioButton
        full                     matlab.ui.control.RadioButton
        RBF_text                 matlab.ui.control.Label
        grbf                     matlab.ui.control.CheckBox
        text30                   matlab.ui.control.Label
        custom                   matlab.ui.control.RadioButton
        customPath               matlab.ui.control.EditField
        customFind               matlab.ui.control.Button
        text55                   matlab.ui.control.Label
        zeroed_FLAGcheck         matlab.ui.control.CheckBox
        outlier_FLAGcheck        matlab.ui.control.CheckBox
        std_text                 matlab.ui.control.Label
        numSTD                   matlab.ui.control.EditField
        balanceType              matlab.ui.control.RadioButton
        balanceType_list         matlab.ui.control.DropDown
        termSelect               matlab.ui.control.RadioButton
        termSelectButton         matlab.ui.control.Button
        numBasisIn               matlab.ui.control.EditField
        selfTerm_pop             matlab.ui.control.DropDown
        runbutton                matlab.ui.control.Button
        cancelbutton             matlab.ui.control.Button
        actionpanel              matlab.ui.container.ButtonGroup
        calibrate                matlab.ui.control.RadioButton
        validate                 matlab.ui.control.RadioButton
        approximate              matlab.ui.control.RadioButton
        calcsv                   matlab.ui.container.Panel
        text5                    matlab.ui.control.Label
        text6                    matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        text8                    matlab.ui.control.Label
        text9                    matlab.ui.control.Label
        c11                      matlab.ui.control.EditField
        c12                      matlab.ui.control.EditField
        c21                      matlab.ui.control.EditField
        c22                      matlab.ui.control.EditField
        c31                      matlab.ui.control.EditField
        c32                      matlab.ui.control.EditField
        c41                      matlab.ui.control.EditField
        c42                      matlab.ui.control.EditField
        c51                      matlab.ui.control.EditField
        c52                      matlab.ui.control.EditField
        calstring                matlab.ui.control.Label
        calPath                  matlab.ui.control.EditField
        calFind                  matlab.ui.control.Button
        text27                   matlab.ui.control.Label
        valcsv                   matlab.ui.container.Panel
        text12                   matlab.ui.control.Label
        text13                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        text15                   matlab.ui.control.Label
        text16                   matlab.ui.control.Label
        v11                      matlab.ui.control.EditField
        v12                      matlab.ui.control.EditField
        v21                      matlab.ui.control.EditField
        v22                      matlab.ui.control.EditField
        v31                      matlab.ui.control.EditField
        v32                      matlab.ui.control.EditField
        v41                      matlab.ui.control.EditField
        v42                      matlab.ui.control.EditField
        v51                      matlab.ui.control.EditField
        v52                      matlab.ui.control.EditField
        valPath                  matlab.ui.control.EditField
        valFind                  matlab.ui.control.Button
        text28                   matlab.ui.control.Label
        valstring                matlab.ui.control.Label
        ini_button               matlab.ui.control.Button
        text34                   matlab.ui.control.Label
        text35                   matlab.ui.control.Label
        text37                   matlab.ui.control.Label
        text38                   matlab.ui.control.Label
        text39                   matlab.ui.control.Label
        text40                   matlab.ui.control.Label
        text41                   matlab.ui.control.Label
        text42                   matlab.ui.control.Label
        text48                   matlab.ui.control.Label
        uibuttongroup1           matlab.ui.container.ButtonGroup
        anova_FLAGcheck          matlab.ui.control.CheckBox
        anova_pct_text           matlab.ui.control.Label
        anova_pct                matlab.ui.control.EditField
        stableRec_FLAGcheck      matlab.ui.control.CheckBox
        text52                   matlab.ui.control.Label
        appcsv                   matlab.ui.container.Panel
        text47                   matlab.ui.control.Label
        text46                   matlab.ui.control.Label
        a42                      matlab.ui.control.EditField
        a41                      matlab.ui.control.EditField
        a32                      matlab.ui.control.EditField
        a31                      matlab.ui.control.EditField
        a22                      matlab.ui.control.EditField
        a21                      matlab.ui.control.EditField
        text43                   matlab.ui.control.Label
        text29                   matlab.ui.control.Label
        appFind                  matlab.ui.control.Button
        appPath                  matlab.ui.control.EditField
        text23                   matlab.ui.control.Label
        a12                      matlab.ui.control.EditField
        a11                      matlab.ui.control.EditField
        text22                   matlab.ui.control.Label
        load_settings_button     matlab.ui.control.Button
        NASAImage                matlab.ui.control.Image
        RiceImage                matlab.ui.control.Image
    end

    
    methods (Access = private)
        function a = a12rc(app, a1)
            %converts spreadsheet notation "A1" to row and column numbers  (0-based)
            alpha_ind = find(isletter(a1));
            alpha = abs(upper(a1(alpha_ind)))-65;
            c = alpha;
            a1(alpha_ind) = [];
            r = str2num(a1)-1;
            a = [r c];
        end
        
        function savePath = loadCSV(app, cva, output_location)
            % This function pre-loads the csv's and saves the data as .mat for quicker
            % reading.
            % Input: type - String that changes depending on whether loading
            % calibration, validation, or approximation data.
            switch cva.type
                case 'calibrate'
                    cal = cva;
            
                    loadCapacities =     csvread(cal.Path,cal.CSV(1,1),cal.CSV(1,2),cal.Range{1});
                    natzeros =           csvread(cal.Path,cal.CSV(2,1),cal.CSV(2,2),cal.Range{2});
            
                    %Read series labels using 'readtable': JRP 19 June 19
                    A=extractAfter(cal.Range{3},'..');
                    bottom=str2double(regexp(A,'\d*','Match'));
                    opts=delimitedTextImportOptions('DataLines',[cal.CSV(3,1)+1 bottom]);
                    series_bulk=readtable(cal.Path,opts);
                    series=str2double(table2array(series_bulk(:,cal.CSV(3,2)+1)));
                    series2=table2array(series_bulk(:,cal.CSV(3,2)+2));
                    pointID=table2array(series_bulk(:,cal.CSV(3,2)));
                    clear A bottom opts series_bulk
            
                    %         series =             csvread(cal.Path,cal.CSV(3,1),cal.CSV(3,2),cal.Range{3});
            
                    targetMatrix0 =      csvread(cal.Path,cal.CSV(4,1),cal.CSV(4,2),cal.Range{4});
                    excessVec0 =         csvread(cal.Path,cal.CSV(5,1),cal.CSV(5,2),cal.Range{5});
            
                    try
                        %             l_label1         = rc2a1([cal.CSV(1,1)-4, cal.CSV(1,2)]);
                        %             l_label2         = rc2a1([cal.CSV(1,1)-4, cal.loadend(2)]);
                        % %            [~,loadlabels,~] = xlsread(cal.Path,[l_label1,':',l_label2]);  %AJM 4_20_19
                        %             [~,loadlabels,~] = csvread(cal.Path,[l_label1,':',l_label2]);
            
                        %START: new approach, JRP 11 June 19
                        file=fopen(cal.Path); %open file
                        all_text1 = textscan(file,'%s','Delimiter','\n'); %read in all text
                        splitlabelRow=cellstr(strsplit(string(all_text1{1}{cal.CSV(1,1)-3}),',','CollapseDelimiters',false)); %Extract row with labels
                        fclose(file); %close file
                        loadlabels=splitlabelRow(cal.CSV(1,2)+1:cal.loadend(2)+1); %extract load labels
                        voltlabels=splitlabelRow(cal.CSV(2,2)+1:cal.voltend(2)+1); %extract voltage labels
                        % read in load and voltage units, JRP 11 July 19
                        splitunitRow=cellstr(strsplit(string(all_text1{1}{cal.CSV(1,1)-1}),',','CollapseDelimiters',false)); %extract row with units
                        loadunits=splitunitRow(cal.CSV(1,2)+1:cal.loadend(2)+1); %extract load units
                        voltunits=splitunitRow(cal.CSV(2,2)+1:cal.voltend(2)+1); %extract voltage units
            
                        try
                            %Eliminate rows with ";" in first column
                            lastrow=a12rc(extractAfter(cal.Range{3},".."));
                            all_text_points=all_text1{1}(cal.CSV(4,1)+1:lastrow(1)+1);
                            for i=1:size(all_text_points,1)
                                all_text_points_split(i,:)=cellstr(strsplit(string(all_text_points(i)),',','CollapseDelimiters',false)); %Extract row with labels
                            end
                            first_col=all_text_points_split(:,1);
                            ignore_row=find(contains(first_col,';')); %Find rows with semicolons in the first column
            
                            excessVec0(ignore_row,:)=[];
                            pointID(ignore_row,:)=[];
                            series(ignore_row,:)=[];
                            series2(ignore_row,:)=[];
                            targetMatrix0(ignore_row,:)=[];
                        catch
                            fprintf('\n UNABLE TO REMOVE ROWS FLAGGED WITH ";" FROM INPUT FILE \n')
                        end
            
                        try
                            %START: find file description and balance name: JRP 25 July 19
                            description_i=find(contains(all_text1{1},'DESCRIPTION'));
                            assert(any(description_i)) %intentional error to get to cach block if 'BALANCE_NAME' is not found
                            descriptionRow=cellstr(strsplit(string(all_text1{1}{description_i}),',','CollapseDelimiters',false)); %Extract row with data description
                            description=descriptionRow(find(contains(descriptionRow,'DESCRIPTION'))+1);
                        catch
                            description={'NO DESCRIPTION FOUND'};
                        end
                        clear description_i descriptionRow
            
                        try
                            balance_i=find(contains(all_text1{1},'BALANCE_NAME'));
                            assert(any(balance_i)) %intentional error to get to cach block if 'BALANCE_NAME' is not found
                            balanceRow=cellstr(strsplit(string(all_text1{1}{balance_i}),',','CollapseDelimiters',false)); %Extract row with balance name
                            balance_type=balanceRow(find(contains(balanceRow,'BALANCE_NAME'))+1);
                        catch
                            balance_type={'NO BALANCE NAME FOUND'};
                        end
                        clear balance_i balanceRow
                        %END:find file description and balance name: JRP 25 July 19
                        clear file label_text1 splitlabelRow splitunitRow
                        %END: new approach, JRP 11 June 19
            
                        try
                            splitlabelRow=cellstr(strsplit(string(all_text1{1}{cal.CSV(1,1)-3}),',','CollapseDelimiters',false)); %Extract row with labels
            
                        end
            
                    end
            
                    [~,calName,~] = fileparts(cal.Path);
                    fileName = [calName,'.cal'];
                    savePath=fullfile(output_location,fileName);
            
                    clear cva calName CurrentPath
                    save(savePath);
                case 'validate'
                    val = cva;
            
                    loadCapacitiesvalid =    csvread(val.Path,val.CSV(1,1),val.CSV(1,2),val.Range{1});
                    natzerosvalid =          csvread(val.Path,val.CSV(2,1),val.CSV(2,2),val.Range{2});
            
                    %Read series labels using 'readtable': JRP 19 June 19
                    A=extractAfter(val.Range{3},'..');
                    bottom=str2double(regexp(A,'\d*','Match'));
                    opts=delimitedTextImportOptions('DataLines',[val.CSV(3,1)+1 bottom]);
                    series_bulk=readtable(val.Path,opts);
                    seriesvalid=str2double(table2array(series_bulk(:,val.CSV(3,2)+1)));
                    series2valid=table2array(series_bulk(:,val.CSV(3,2)+2));
                    pointIDvalid=table2array(series_bulk(:,val.CSV(3,2)));
                    clear A bottom opts series_bulk
                    %         seriesvalid =            csvread(val.Path,val.CSV(3,1),val.CSV(3,2),val.Range{3});
            
                    targetMatrixvalid =      csvread(val.Path,val.CSV(4,1),val.CSV(4,2),val.Range{4});
                    excessVecvalid =         csvread(val.Path,val.CSV(5,1),val.CSV(5,2),val.Range{5});
            
                    try
                        file=fopen(val.Path); %open file
                        all_text1 = textscan(file,'%s','Delimiter','\n'); %read in all text
                        fclose(file); %close file
                        %Eliminate rows with ";" in first column
                        lastrow=a12rc(extractAfter(val.Range{3},".."));
                        all_text_points=all_text1{1}(val.CSV(4,1)+1:lastrow(1)+1);
                        for i=1:size(all_text_points,1)
                            all_text_points_split(i,:)=cellstr(strsplit(string(all_text_points(i)),',','CollapseDelimiters',false)); %Extract row with labels
                        end
                        first_col=all_text_points_split(:,1);
                        ignore_row=find(contains(first_col,';')); %Find rows with semicolons in the first column
            
                        excessVecvalid(ignore_row,:)=[];
                        pointIDvalid(ignore_row,:)=[];
                        seriesvalid(ignore_row,:)=[];
                        series2valid(ignore_row,:)=[];
                        targetMatrixvalid(ignore_row,:)=[];
                    catch
                        fprintf('\n UNABLE TO REMOVE ROWS FLAGGED WITH ";" FROM INPUT FILE \n')
                    end
            
                    [~,valName,~] = fileparts(val.Path);
                    fileNamevalid = [valName,'.val'];
                    savePathvalid=fullfile(output_location,fileNamevalid);
            
                    clear cva valName CurrentPath
                    save(savePathvalid);
                    savePath = savePathvalid;
                case 'approximate'
                    app = cva;
            
                    loadCapacitiesapprox =    csvread(app.Path,app.CSV(1,1),app.CSV(1,2),app.Range{1});
                    natzerosapprox =          csvread(app.Path,app.CSV(2,1),app.CSV(2,2),app.Range{2});
            
                    %Read series labels using 'readtable': JRP 19 June 19
                    A=extractAfter(app.Range{3},'..');
                    bottom=str2double(regexp(A,'\d*','Match'));
                    opts=delimitedTextImportOptions('DataLines',[app.CSV(3,1)+1 bottom]);
                    series_bulk=readtable(app.Path,opts);
                    seriesapprox=str2double(table2array(series_bulk(:,app.CSV(3,2)+1)));
                    series2approx=table2array(series_bulk(:,app.CSV(3,2)+2));
                    pointIDapprox=table2array(series_bulk(:,app.CSV(3,2)));
                    clear A bottom opts series_bulk
                    %         seriesapprox =            csvread(app.Path,app.CSV(3,1),app.CSV(3,2),app.Range{3});
            
                    excessVecapprox =         csvread(app.Path,app.CSV(4,1),app.CSV(4,2),app.Range{4});
            
                    try
                        file=fopen(app.Path); %open file
                        all_text1 = textscan(file,'%s','Delimiter','\n'); %read in all text
                        fclose(file); %close file
                        %Eliminate rows with ";" in first column
                        lastrow=a12rc(extractAfter(app.Range{3},".."));
                        all_text_points=all_text1{1}(app.CSV(4,1)+1:lastrow(1)+1);
                        for i=1:size(all_text_points,1)
                            all_text_points_split(i,:)=cellstr(strsplit(string(all_text_points(i)),',','CollapseDelimiters',false)); %Extract row with labels
                        end
                        first_col=all_text_points_split(:,1);
                        ignore_row=find(contains(first_col,';')); %Find rows with semicolons in the first column
            
                        excessVecapprox(ignore_row,:)=[];
                        pointIDapprox(ignore_row,:)=[];
                        seriesapprox(ignore_row,:)=[];
                        series2approx(ignore_row,:)=[];
                    catch
                        fprintf('\n UNABLE TO REMOVE ROWS FLAGGED WITH ";" FROM INPUT FILE \n')
                    end
            
                    [~,appName,~] = fileparts(app.Path);
                    fileNameapprox = [appName,'.app'];
                    savePathapprox=fullfile(output_location,fileNameapprox);
            
                    clear cva appName CurrentPath
                    save(savePathapprox);
                    savePath = savePathapprox;
            end
        end
        
        function loadSettings(app, fullfileName)
            %Function loads and sets GUI settings from .ini file.
            %Used to load default settings or settings from previous run
            
            [~,fileName,~]=fileparts(fullfileName);
            
            if exist(fullfileName,'file')
                try
                    load(fullfileName,'-mat');
            
                    versionCheck(app, default);
            
                    %set(app.tares_FLAGcheck,'Value',default.tares);
                    set(app.disp_FLAGcheck,'Value',default.disp);
                    %set(app.grbftares_FLAGcheck,'Value',default.grbftares);
                    set(app.print_FLAGcheck,'Value',default.print);
                    set(app.res_FLAGcheck,'Value',default.res);
                    set(app.hist_FLAGcheck,'Value',default.hist);
                    set(app.outlier_FLAGcheck,'Value',default.outlier);
                    set(app.numSTD,'Value',default.numSTD);
                    set(app.zeroed_FLAGcheck,'Value',default.zeroed);
                    set(app.corr_FLAGcheck,'Value',default.corr);
                    set(app.rescorr_FLAGcheck,'Value',default.rescorr);
                    set(app.excel_FLAGcheck,'Value',default.excel);
            
                    set(app.calibrate,'Value',default.calibrate);
                    set(app.calPath,'Value',default.calPath);
                    set(app.c11,'Value',default.calRange{1,1});
                    set(app.c12,'Value',default.calRange{1,2});
                    set(app.c21,'Value',default.calRange{2,1});
                    set(app.c22,'Value',default.calRange{2,2});
                    set(app.c31,'Value',default.calRange{3,1});
                    set(app.c32,'Value',default.calRange{3,2});
                    set(app.c41,'Value',default.calRange{4,1});
                    set(app.c42,'Value',default.calRange{4,2});
                    set(app.c51,'Value',default.calRange{5,1});
                    set(app.c52,'Value',default.calRange{5,2});
            
            
                    set(app.validate,'Value',default.validate);
                    set(app.valPath,'Value',default.valPath);
                    set(app.v11,'Value',default.valRange{1,1});
                    set(app.v12,'Value',default.valRange{1,2});
                    set(app.v21,'Value',default.valRange{2,1});
                    set(app.v22,'Value',default.valRange{2,2});
                    set(app.v31,'Value',default.valRange{3,1});
                    set(app.v32,'Value',default.valRange{3,2});
                    set(app.v41,'Value',default.valRange{4,1});
                    set(app.v42,'Value',default.valRange{4,2});
                    set(app.v51,'Value',default.valRange{5,1});
                    set(app.v52,'Value',default.valRange{5,2});
            
            
                    set(app.approximate,'Value',default.approximate);
                    set(app.appPath,'Value',default.appPath);
                    set(app.a11,'Value',default.appRange{1,1});
                    set(app.a12,'Value',default.appRange{1,2});
                    set(app.a21,'Value',default.appRange{2,1});
                    set(app.a22,'Value',default.appRange{2,2});
                    set(app.a31,'Value',default.appRange{3,1});
                    set(app.a32,'Value',default.appRange{3,2});
                    set(app.a41,'Value',default.appRange{4,1});
                    set(app.a42,'Value',default.appRange{4,2});
            
            
                    switch default.action
                        case 'calibrate', app.calibrate.Value=1;
                        case 'validate', app.validate.Value=1;
                        case 'approximate', app.approximate.Value=1;
                    end
            
                    set(app.full,'Value',default.full);
                    set(app.truncated,'Value',default.truncated);
                    set(app.linear,'Value',default.linear);
                    set(app.custom,'Value',default.custom);
                    set(app.customPath,'Value',default.customPath);
                    set(app.balanceType,'Value',default.balanceType);
                    set(app.balanceType_list,'Value',default.balanceType_list);
                    set(app.termSelect,'Value',default.termSelect);
                    set(app.termSelectButton,'Tooltip',default.termInclude);
            
                    set(app.grbf,'Value',default.grbf);
                    set(app.numBasisIn,'Value',default.basis);
                    set(app.selfTerm_pop,'Items',default.selfTerm_pop_str);
                    set(app.selfTerm_pop,'Value',default.selfTerm_pop_val);
            
            
                    %set(app.grbfcoeff_FLAGcheck,'Value',default.grbf_coeff);
                    %set(app.loglog_FLAGcheck,'Value',default.loglog);
            
                    set(app.anova_FLAGcheck,'Value',default.anova);
            
                    set(app.BALFIT_Matrix_FLAGcheck,'Value',default.BALFIT_Matrix);
                    set(app.BALFIT_ANOVA_FLAGcheck,'Value',default.BALFIT_ANOVA);
                    set(app.Rec_Model_FLAGcheck,'Value',default.Rec_Model);
                    set(app.anova_pct,'Value',default.anova_pct);
                    set(app.approx_and_PI_print,'Value',default.approx_and_PI_print);
            
                    set(app.output_to_calib_FLAG,'Value',default.output_to_calib_FLAG);
                    set(app.subfolder_FLAG,'Value',default.subfolder_FLAG);
                    set(app.output_location,'Value',default.output_location);
                    set(app.calib_model_save_FLAG,'Value',default.calib_model_save_FLAG);
                    set(app.input_save_FLAG,'Value',default.input_save_FLAG);
                    set(app.stableRec_FLAGcheck,'Value',default.stableRec_FLAGcheck);
                    if isfield(default,'runID')
                        disp(['Loaded Settings from Test: ',default.runID])
                    else
                        disp('Default Settings Loaded')
                    end
                catch
                    disp(['Unable to fully load settings. ',fileName,'.ini may be outdated or incompatible with GUI.']);
                end
            end
        end
        
        
        function a1 = rc2a1(app, a)
            r = a(1); c = a(2);
            alpha = char(c+65);
            num = int2str(r+1);
            a1 = [alpha, num];
        end
        
        function saveSettings(app, fileName, filePath, runID)
            global VERSION
            default.version = VERSION;
            
            if nargin==4
                default.runID=runID;
            end
            
            %default.tares = get(app.tares_FLAGcheck,'Value');
            default.disp = get(app.disp_FLAGcheck,'Value');
            %default.grbftares = get(app.grbftares_FLAGcheck,'Value');
            default.print = get(app.print_FLAGcheck,'Value');
            default.res = get(app.res_FLAGcheck,'Value');
            default.hist = get(app.hist_FLAGcheck,'Value');
            default.outlier = get(app.outlier_FLAGcheck,'Value');
            default.numSTD = get(app.numSTD,'Value');
            default.zeroed = get(app.zeroed_FLAGcheck,'Value');
            default.corr = get(app.corr_FLAGcheck,'Value');
            default.rescorr = get(app.rescorr_FLAGcheck,'Value');
            default.excel = get(app.excel_FLAGcheck,'Value');
            
            default.action = get(get(app.actionpanel,'SelectedObject'),'tag');
            default.calibrate = get(app.calibrate,'Value');
            default.calPath = get(app.calPath,'Value');
            default.calRange{1,1} = get(app.c11,'Value');
            default.calRange{1,2} = get(app.c12,'Value');
            default.calRange{2,1} = get(app.c21,'Value');
            default.calRange{2,2} = get(app.c22,'Value');
            default.calRange{3,1} = get(app.c31,'Value');
            default.calRange{3,2} = get(app.c32,'Value');
            default.calRange{4,1} = get(app.c41,'Value');
            default.calRange{4,2} = get(app.c42,'Value');
            default.calRange{5,1} = get(app.c51,'Value');
            default.calRange{5,2} = get(app.c52,'Value');
            
            default.validate = get(app.validate,'Value');
            default.valPath = get(app.valPath,'Value');
            default.valRange{1,1} = get(app.v11,'Value');
            default.valRange{1,2} = get(app.v12,'Value');
            default.valRange{2,1} = get(app.v21,'Value');
            default.valRange{2,2} = get(app.v22,'Value');
            default.valRange{3,1} = get(app.v31,'Value');
            default.valRange{3,2} = get(app.v32,'Value');
            default.valRange{4,1} = get(app.v41,'Value');
            default.valRange{4,2} = get(app.v42,'Value');
            default.valRange{5,1} = get(app.v51,'Value');
            default.valRange{5,2} = get(app.v52,'Value');
            
            default.approximate = get(app.approximate,'Value');
            default.appPath = get(app.appPath,'Value');
            default.appRange{1,1} = get(app.a11,'Value');
            default.appRange{1,2} = get(app.a12,'Value');
            default.appRange{2,1} = get(app.a21,'Value');
            default.appRange{2,2} = get(app.a22,'Value');
            default.appRange{3,1} = get(app.a31,'Value');
            default.appRange{3,2} = get(app.a32,'Value');
            default.appRange{4,1} = get(app.a41,'Value');
            default.appRange{4,2} = get(app.a42,'Value');
            
            default.full = get(app.full,'Value');
            default.truncated = get(app.truncated,'Value');
            default.linear = get(app.linear,'Value');
            default.custom = get(app.custom,'Value');
            default.customPath = get(app.customPath,'Value');
            default.balanceType = get(app.balanceType,'Value');
            default.balanceType_list=get(app.balanceType_list, 'Value');
            default.termSelect=get(app.termSelect,'Value');
            default.termInclude=app.termSelectButton.Tooltip;
            default.grbf = get(app.grbf,'Value');
            default.basis = get(app.numBasisIn,'Value');
            default.selfTerm_pop_str= get(app.selfTerm_pop,'Items');
            default.selfTerm_pop_val= get(app.selfTerm_pop,'Value');
            %default.grbf_coeff = get(app.grbfcoeff_FLAGcheck,'Value');
            %default.loglog = get(app.loglog_FLAGcheck,'Value');
            
            default.anova = get(app.anova_FLAGcheck,'Value');
            default.BALFIT_Matrix = get(app.BALFIT_Matrix_FLAGcheck,'Value');
            default.BALFIT_ANOVA = get(app.BALFIT_ANOVA_FLAGcheck,'Value');
            default.Rec_Model = get(app.Rec_Model_FLAGcheck,'Value');
            default.anova_pct = get(app.anova_pct,'Value');
            default.approx_and_PI_print = get(app.approx_and_PI_print,'Value');
            
            default.output_location=get(app.output_location,'Value');
            default.output_to_calib_FLAG=get(app.output_to_calib_FLAG,'Value');
            default.subfolder_FLAG=get(app.subfolder_FLAG,'Value');
            default.calib_model_save_FLAG=get(app.calib_model_save_FLAG,'Value');
            default.input_save_FLAG=get(app.input_save_FLAG,'Value');
            
            default.stableRec_FLAGcheck=get(app.stableRec_FLAGcheck,'Value');
            
            % [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
            fullfileName = [filePath,filesep,fileName,'.ini'];
            save(fullfileName,'default');
        end
        
        function selfTerm_strSet(app)
            pos_str={'No Early Termination','Validation Error Termination','Prediction Interval Termination','VIF + Prediction Interval Termination'}; %Possible self-termination options
            str_include=logical([1,0,0,0]); %Logical array of what options should be possible
            if app.validate.Value==1 %If validation data is provided
                str_include(2)=1;
            end
            if app.anova_FLAGcheck.Value==1 %If ANOVA is turned on
                str_include(3)=1;
                str_include(4)=1;
            end
            %Retrieve current popup state
            cur_str=app.selfTerm_pop.Items;
            cur_val=app.selfTerm_pop.Value;
            
            if ischar(cur_str) %If currently only 1 option for popup
                new_val=pos_str(1);
            else
                match=strcmp(cur_val,pos_str(str_include)); %Find if current selected string matches any of new possible options
                if any(match)
                    new_val=cur_val; %Set new value to maintain selection
                else
                    new_val=pos_str(1); %No longer option
                end
            end
            %Update popup
            set(app.selfTerm_pop,'Items',pos_str(str_include));
            set(app.selfTerm_pop,'Value',new_val);
        end
        
        function versionCheck(app, default)
            global VERSION
            mesg = 'An outdated version of default.ini was detected.';
            if ~isfield(default, 'version')
                error(mesg);
            elseif default.version ~= VERSION
                error(mesg);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AOX_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to AOX_GUI (see VARARGIN)
%             set(app.figure1, 'units', 'normalized', 'position', [0.15 0.1 0.5 0.8])
%             set(app.output_location,'String',pwd);
%             global VERSION
%             VERSION = 16;
%             
%             try
%             
%                 [nasalogo,~,aln] = imread('nasa.png','BackgroundColor',[0.941, 0.941, 0.941]);
%                 axes(app.axesNASA);
%                 imshow(nasalogo, []);
%             
%                 [ricelogo,~,alr] = imread('rice.png','BackgroundColor',[0.941, 0.941, 0.941]);
%                 axes(app.axesRice);
%                 imshow(ricelogo, []);
%             end
%             
%             % Choose default command line output for AOX_GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             % UIWAIT makes AOX_GUI wait for user response (see UIRESUME)
            [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
            fileName = [CurrentPath,filesep,'default.ini'];
            
%             app.termInclude=zeros(10,1); %Initialize for sub gui
            
            loadSettings(app, fileName);
            
            outlier_FLAGcheck_Callback(app);
            calPath_Callback(app);
            valPath_Callback(app);
            appPath_Callback(app);
            grbf_Callback(app);
            anova_FLAGcheck_Callback(app)
            output_to_calib_FLAG_Callback(app)
            
            modelPanel_SelectionChangeFcn(app);
            actionpanel_SelectionChangeFcn(app);
%             uiwait(app.figure1);
        end

        % Selection changed function: actionpanel
        function actionpanel_SelectionChangeFcn(app, event)
            % hObject    handle to the selected object in actionpanel
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            calPath_Callback(app);
            set(app.calPath, 'Enable', 'on');
            set(app.calFind, 'Enable', 'on');
            selfTerm_strSet(app);
            
            if (app.calibrate.Value == 1)
                set(app.valPath, 'Enable', 'off');
                set(app.valFind, 'Enable', 'off');
                %set(app.valSave, 'Enable', 'off');
                set(app.v11, 'Enable', 'off');
                set(app.v12, 'Enable', 'off');
                set(app.v21, 'Enable', 'off');
                set(app.v22, 'Enable', 'off');
                set(app.v31, 'Enable', 'off');
                set(app.v32, 'Enable', 'off');
                set(app.v41, 'Enable', 'off');
                set(app.v42, 'Enable', 'off');
                set(app.v51, 'Enable', 'off');
                set(app.v52, 'Enable', 'off');
            
                set(app.appPath, 'Enable', 'off');
                set(app.appFind, 'Enable', 'off');
                %set(app.appSave, 'Enable', 'off');
                set(app.a11, 'Enable', 'off');
                set(app.a12, 'Enable', 'off');
                set(app.a21, 'Enable', 'off');
                set(app.a22, 'Enable', 'off');
                set(app.a31, 'Enable', 'off');
                set(app.a32, 'Enable', 'off');
                set(app.a41, 'Enable', 'off');
                set(app.a42, 'Enable', 'off');
                %set(app.zeroed_FLAGcheck,'Enable','off');
            elseif app.validate.Value==1
                set(app.valPath, 'Enable', 'on');
                set(app.valFind, 'Enable', 'on');
                %set(app.valSave, 'Enable', 'on');
                set(app.v11, 'Enable', 'on');
                set(app.v12, 'Enable', 'on');
                set(app.v21, 'Enable', 'on');
                set(app.v22, 'Enable', 'on');
                set(app.v31, 'Enable', 'on');
                set(app.v32, 'Enable', 'on');
                set(app.v41, 'Enable', 'on');
                set(app.v42, 'Enable', 'on');
                set(app.v51, 'Enable', 'on');
                set(app.v52, 'Enable', 'on');
                valPath_Callback(app);
            
                set(app.appPath, 'Enable', 'off');
                set(app.appFind, 'Enable', 'off');
                %set(app.appSave, 'Enable', 'off');
                set(app.a11, 'Enable', 'off');
                set(app.a12, 'Enable', 'off');
                set(app.a21, 'Enable', 'off');
                set(app.a22, 'Enable', 'off');
                set(app.a31, 'Enable', 'off');
                set(app.a32, 'Enable', 'off');
                set(app.a41, 'Enable', 'off');
                set(app.a42, 'Enable', 'off');
                %set(app.zeroed_FLAGcheck,'Enable','off');
            
            elseif app.approximate.Value==1
                set(app.valPath, 'Enable', 'off');
                set(app.valFind, 'Enable', 'off');
                %set(app.valSave, 'Enable', 'off');
                set(app.v11, 'Enable', 'off');
                set(app.v12, 'Enable', 'off');
                set(app.v21, 'Enable', 'off');
                set(app.v22, 'Enable', 'off');
                set(app.v31, 'Enable', 'off');
                set(app.v32, 'Enable', 'off');
                set(app.v41, 'Enable', 'off');
                set(app.v42, 'Enable', 'off');
                set(app.v51, 'Enable', 'off');
                set(app.v52, 'Enable', 'off');
            
                set(app.appPath, 'Enable', 'on');
                set(app.appFind, 'Enable', 'on');
                %set(app.appSave, 'Enable', 'on');
                set(app.a11, 'Enable', 'on');
                set(app.a12, 'Enable', 'on');
                set(app.a21, 'Enable', 'on');
                set(app.a22, 'Enable', 'on');
                set(app.a31, 'Enable', 'on');
                set(app.a32, 'Enable', 'on');
                set(app.a41, 'Enable', 'on');
                set(app.a42, 'Enable', 'on');
                appPath_Callback(app);
                %set(app.zeroed_FLAGcheck,'Enable','on');
            end
        end

        % Value changed function: anova_FLAGcheck
        function anova_FLAGcheck_Callback(app, event)
            % hObject    handle to anova_FLAGcheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of anova_FLAGcheck
            selfTerm_strSet(app);
            
            if app.anova_FLAGcheck.Value == 0
                set(app.BALFIT_ANOVA_FLAGcheck,'Enable','off','Value',0);
                set(app.Rec_Model_FLAGcheck,'Enable','off','Value',0);
                set(app.anova_pct,'Enable','off');
                set(app.anova_pct_text,'Enable','off');
                set(app.stableRec_FLAGcheck,'Enable','off','Value',0);
                set(app.approx_and_PI_print,'Enable','off','Value',0);
            else
                set(app.BALFIT_ANOVA_FLAGcheck,'Enable','on');
                set(app.Rec_Model_FLAGcheck,'Enable','on');
                set(app.anova_pct,'Enable','on');
                set(app.anova_pct_text,'Enable','on');
                set(app.stableRec_FLAGcheck,'Enable','on');
                set(app.approx_and_PI_print,'Enable','on');
            end
        end

        % Button pushed function: appFind
        function appFind_Callback(app, event)
            % hObject    handle to appFind (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName, PathName] = uigetfile('*.csv;*.app');
            if FileName ~= 0
                [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
                CurrentPath = [CurrentPath,'\'];
                if strcmp(CurrentPath,PathName)
                    FullPath = FileName;
                else
                    FullPath = [PathName,FileName];
                end
                set(app.appPath,'Value',FullPath)
            end
            appPath_Callback(app, event)
        end

        % Value changed function: appPath
        function appPath_Callback(app, event)
            % hObject    handle to appPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of appPath as text
            %        str2double(get(hObject,'String')) returns contents of appPath as a double
            [~,~,ext] = fileparts(app.appPath.Value);
            try
                if strcmp(ext,'.csv')
                    %set(app.appSave, 'Enable', 'on');
                    set(app.a11, 'Enable', 'on');
                    set(app.a12, 'Enable', 'on');
                    set(app.a21, 'Enable', 'on');
                    set(app.a22, 'Enable', 'on');
                    set(app.a31, 'Enable', 'on');
                    set(app.a32, 'Enable', 'on');
                    set(app.a41, 'Enable', 'on');
                    set(app.a42, 'Enable', 'on');
                else
                    %set(app.appSave, 'Enable', 'off');
                    if strcmp(ext,'.app')
                        load(app.appPath.Value, '-mat', 'app');
                        splitrange = split(app.Range,'..');
                    else
                        splitrange=cell(1,4,2);
                    end
            
                    set(app.a11, 'Enable', 'off', 'String', splitrange{1,1,1});
                    set(app.a12, 'Enable', 'off', 'String', splitrange{1,1,2});
                    set(app.a21, 'Enable', 'off', 'String', splitrange{1,2,1});
                    set(app.a22, 'Enable', 'off', 'String', splitrange{1,2,2});
                    set(app.a31, 'Enable', 'off', 'String', splitrange{1,3,1});
                    set(app.a32, 'Enable', 'off', 'String', splitrange{1,3,2});
                    set(app.a41, 'Enable', 'off', 'String', splitrange{1,4,1});
                    set(app.a42, 'Enable', 'off', 'String', splitrange{1,4,2});
                end
            catch
                disp('Problem occurred while reading Approximation file');
            end
        end

        % Button pushed function: calFind
        function calFind_Callback(app, event)
            % hObject    handle to calFind (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName, PathName] = uigetfile('*.csv;*.cal');
            if FileName ~= 0
                [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
                CurrentPath = [CurrentPath,filesep];
                if strcmp(CurrentPath,PathName)
                    FullPath = FileName;
                else
                    FullPath = [PathName,FileName];
                end
                set(app.calPath,'Value',FullPath)
            end
            calPath_Callback(app, event);
        end

        % Value changed function: calPath
        function calPath_Callback(app, event)
            % hObject    handle to calPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of calPath as text
            %        str2double(get(hObject,'String')) returns contents of calPath as a double
            [~,~,ext] = fileparts(app.calPath.Value);
            try
                if strcmp(ext,'.csv')
                    %set(app.calSave, 'Enable', 'on');
                    set(app.c11, 'Enable', 'on');
                    set(app.c12, 'Enable', 'on');
                    set(app.c21, 'Enable', 'on');
                    set(app.c22, 'Enable', 'on');
                    set(app.c31, 'Enable', 'on');
                    set(app.c32, 'Enable', 'on');
                    set(app.c41, 'Enable', 'on');
                    set(app.c42, 'Enable', 'on');
                    set(app.c51, 'Enable', 'on');
                    set(app.c52, 'Enable', 'on');
                else
                    %set(app.calSave, 'Enable', 'off');
                    if strcmp(ext,'.cal')
                        load(get(app.calPath,'Value'), '-mat', 'cal');
                        splitrange = split(cal.Range,'..');
                    else
                        splitrange=cell(1,5,2);
                    end
            
                    set(app.c11, 'Enable', 'off', 'Value', splitrange{1,1,1});
                    set(app.c12, 'Enable', 'off', 'Value', splitrange{1,1,2});
                    set(app.c21, 'Enable', 'off', 'Value', splitrange{1,2,1});
                    set(app.c22, 'Enable', 'off', 'Value', splitrange{1,2,2});
                    set(app.c31, 'Enable', 'off', 'Value', splitrange{1,3,1});
                    set(app.c32, 'Enable', 'off', 'Value', splitrange{1,3,2});
                    set(app.c41, 'Enable', 'off', 'Value', splitrange{1,4,1});
                    set(app.c42, 'Enable', 'off', 'Value', splitrange{1,4,2});
                    set(app.c51, 'Enable', 'off', 'Value', splitrange{1,5,1});
                    set(app.c52, 'Enable', 'off', 'Value', splitrange{1,5,2});
                end
                output_to_calib_FLAG_Callback(app)
            catch
                disp('Problem occurred while reading Calibration file')
            end
        end

        % Button pushed function: cancelbutton
        function cancelbutton_Callback(app, event)
            % hObject    handle to cancelbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             outStruct.cancel = 1;
%             app.output = outStruct;
%             guidata(hObject,handles);
%             uiresume(app.figure1);
            figure1_CloseRequestFcn(app, event)
        end

        % Button pushed function: customFind
        function customFind_Callback(app, event)
            % hObject    handle to customFind (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName, PathName] = uigetfile('*.csv');
            if FileName ~= 0
                [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
                CurrentPath = [CurrentPath,filesep];
                if strcmp(CurrentPath,PathName)
                    FullPath = FileName;
                else
                    FullPath = [PathName,FileName];
                end
                set(app.customPath,'Value',FullPath)
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.delete
%             % Hint: delete(hObject) closes the figure
%             if isequal(get(hObject, 'waitstatus'), 'waiting')
%                 % The GUI is still in UIWAIT, us UIRESUME
%                 outStruct.cancel = 1;
%                 app.output = outStruct;
%                 guidata(hObject,handles);
%                 uiresume(hObject);
%             else
%                 % The GUI is no longer waiting, just close it
%                 delete(hObject);
%             end
        end

        % Value changed function: grbf
        function grbf_Callback(app, event)
            % hObject    handle to grbf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of grbf
            if get(app.grbf,'Value') == 1
                set(app.numBasisIn,'Enable','on');
                set(app.RBF_text,'Enable','on');
                selfTerm_strSet(app);
                set(app.selfTerm_pop,'Enable','on');
                %set(app.loglog_FLAGcheck,'Enable','on');
                %set(app.grbfcoeff_FLAGcheck,'Enable','on');
                %set(app.grbftares_FLAGcheck,'Enable','on');
            else
                set(app.numBasisIn,'Enable','off');
                set(app.RBF_text,'Enable','off');
                set(app.selfTerm_pop,'Enable','off');
                %set(app.loglog_FLAGcheck,'Enable','off');
                %set(app.grbfcoeff_FLAGcheck,'Enable','off');
                %set(app.grbftares_FLAGcheck,'Enable','off');
            end
        end

        % Button pushed function: ini_button
        function ini_button_Callback(app, event)
            % hObject    handle to ini_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [savePath,~,~] = fileparts(mfilename('fullpath'));
            saveSettings(app,'default',savePath);
            
            set(app.ini_button,'Text','Saving...');
            pause(1);
            set(app.ini_button,'Text','Saved');
            pause(0.5);
            set(app.ini_button,'Text','Set as Default...')
        end

        % Button pushed function: load_settings_button
        function load_settings_button_Callback(app, event)
            % hObject    handle to load_settings_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [set_name,set_path]=uigetfile('*.ini');
            if ~isequal(set_name,0)
                fileName=fullfile(set_path,set_name);
                loadSettings(app, fileName);
            end
        end

        % Selection changed function: modelPanel
        function modelPanel_SelectionChangeFcn(app, event)
            % hObject    handle to the selected object in modelPanel
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            if (app.custom.Value == 1)
                set(app.customPath, 'Enable', 'on');
                set(app.customFind, 'Enable', 'on');
            else
                set(app.customPath, 'Enable', 'off');
                set(app.customFind, 'Enable', 'off');
            end
            if (app.balanceType.Value == 1)
                set(app.balanceType_list, 'Enable', 'on');
            else
                set(app.balanceType_list, 'Enable', 'off');
            end
            if (app.termSelect.Value==1)
                set(app.termSelectButton, 'Enable', 'on');
            else
                set(app.termSelectButton, 'Enable', 'off');
            end
        end

        % Value changed function: outlier_FLAGcheck
        function outlier_FLAGcheck_Callback(app, event)
            % hObject    handle to outlier_FLAGcheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of outlier_FLAGcheck
            if get(app.outlier_FLAGcheck,'Value') == 1
                set(app.zeroed_FLAGcheck,'Enable','on');
                set(app.numSTD,'Enable','on');
                set(app.std_text,'Enable','on');
            
            else
                set(app.zeroed_FLAGcheck,'Enable','off');
                set(app.numSTD,'Enable','off');
                set(app.std_text,'Enable','off');
            end
        end

        % Button pushed function: output_location_button
        function output_location_button_Callback(app, event)
            % hObject    handle to output_location_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selpath=uigetdir;
            set(app.output_location,'Value',selpath);
        end

        % Value changed function: output_to_calib_FLAG
        function output_to_calib_FLAG_Callback(app, event)
            % hObject    handle to output_to_calib_FLAG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of output_to_calib_FLAG
            if get(app.output_to_calib_FLAG,'Value') == 0
                set(app.output_location_button,'Enable','on');
                set(app.output_location,'Enable','on');
                %     set(app.output_location,'String',pwd);
            else
                set(app.output_location_button,'Enable','off');
                set(app.output_location,'Enable','off');
                [calib_path,~,~]=fileparts(get(app.calPath,'Value'));
                if isempty(calib_path)==0
                    %         [calib_path,~,~] = fileparts(calib_path);
                else
                    calib_path=pwd;
                end
                set(app.output_location,'Value',calib_path);
            end
        end

        % Button pushed function: runbutton
        function runbutton_Callback(app, event)
            % hObject    handle to runbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.runbutton,'Enable','off','Text','...')
            uiresume(app.figure1);
            %outStruct.tares = get(app.tares_FLAGcheck,'Value');
            outStruct.disp = get(app.disp_FLAGcheck,'Value');
            %outStruct.grbftares = get(app.grbftares_FLAGcheck,'Value');
            outStruct.print = get(app.print_FLAGcheck,'Value');
            outStruct.res = get(app.res_FLAGcheck,'Value');
            outStruct.hist = get(app.hist_FLAGcheck,'Value');
            outStruct.outlier = get(app.outlier_FLAGcheck,'Value');
            outStruct.numSTD = str2num(get(app.numSTD,'String'));
            %outStruct.loglog = get(app.loglog_FLAGcheck,'Value');
            outStruct.zeroed = get(app.zeroed_FLAGcheck,'Value');
            outStruct.corr = get(app.corr_FLAGcheck,'Value');
            outStruct.rescorr = get(app.rescorr_FLAGcheck,'Value');
            outStruct.excel = get(app.excel_FLAGcheck,'Value');
            
            switch get(get(app.modelPanel,'SelectedObject'),'Tag')
                case 'full', outStruct.model = 1;
                case 'truncated', outStruct.model = 2;
                case 'linear', outStruct.model = 3;
                case 'custom'
                    outStruct.model = 4;
                    customPath = get(app.customPath,'String');
                    outStruct.customMatrix = csvread(customPath,1,1);
                case 'balanceType'
                    outStruct.model=5;
                    outStruct.balanceEqn=get(app.balanceType_list,'Value');
                case 'termSelect'
                    outStruct.model=6;
                    outStruct.termInclude=zeros(10,1);
                    terms=app.termSelectButton.Tooltip;
                    termList={' F, ',' |F|, ', ' F*F, ', ' F*|F|, ', ' F*G, ', ' |F*G|, ', ' F*|G|, ', ' |F|*G, ', ' F*F*F, ', ' |F*F*F| '};
                    outStruct.termInclude(1)=contains(terms,termList{1});
                    outStruct.termInclude(2)=contains(terms,termList{2});
                    outStruct.termInclude(3)=contains(terms,termList{3});
                    outStruct.termInclude(4)=contains(terms,termList{4});
                    outStruct.termInclude(5)=contains(terms,termList{5});
                    outStruct.termInclude(6)=contains(terms,termList{6});
                    outStruct.termInclude(7)=contains(terms,termList{7});
                    outStruct.termInclude(8)=contains(terms,termList{8});
                    outStruct.termInclude(9)=contains(terms,termList{9});
                    outStruct.termInclude(10)=contains(terms,termList{10});
            end
            
            outStruct.grbf = 1 + get(app.grbf,'Value');
            outStruct.basis = str2num(get(app.numBasisIn,'String'));
            outStruct.selfTerm_str=app.selfTerm_pop.String(app.selfTerm_pop.Value);
            
            outStruct.anova = get(app.anova_FLAGcheck,'Value');
            outStruct.BALFIT_Matrix = get(app.BALFIT_Matrix_FLAGcheck,'Value');
            outStruct.BALFIT_ANOVA = get(app.BALFIT_ANOVA_FLAGcheck,'Value');
            outStruct.Rec_Model = get(app.Rec_Model_FLAGcheck,'Value');
            outStruct.anova_pct= str2num(get(app.anova_pct,'String'));
            outStruct.approx_and_PI_print = get(app.approx_and_PI_print,'Value');
            
            outStruct.stableRec_FLAGcheck=get(app.stableRec_FLAGcheck,'Value');
            outStruct.output_location=get(app.output_location,'String');
            outStruct.subfolder_FLAG=get(app.subfolder_FLAG,'Value');
            outStruct.calib_model_save_FLAG=get(app.calib_model_save_FLAG,'Value');
            outStruct.input_save_FLAG=get(app.input_save_FLAG,'Value');
            
            %Make new subfolder if selected as option
            %Default output location to current directory if empty
            if isempty(outStruct.output_location)==1
                outStruct.output_location=cd;
            end
            
            outStruct.REPORT_NO=datestr(now,'yyyy-mmdd-HHMMSS');
            outStruct.output_location=[outStruct.output_location,filesep];
            if outStruct.subfolder_FLAG==1
                try
                    new_subpath=fullfile(outStruct.output_location,['AOX_BalCal_Results_',outStruct.REPORT_NO]);
                    mkdir(char(new_subpath));
                    outStruct.output_location=[new_subpath,filesep];
                catch
                    fprintf('Unable to create new subfolder. Saving results in: ');
                    fprintf('%s',outStruct.output_location); fprintf('\n');
                end
            end
            
            
            cal.type = 'calibrate';
            cal.Path = get(app.calPath,'String');
            [~,~,calext] = fileparts(cal.Path);
            switch calext
                case '.csv'
                    cal.Range{1} = [get(app.c11,'String'),'..',get(app.c12,'String')];
                    cal.CSV(1,:) = a12rc(get(app.c11,'String'));
                    cal.Range{2} = [get(app.c21,'String'),'..',get(app.c22,'String')];
                    cal.CSV(2,:) = a12rc(get(app.c21,'String'));
                    cal.Range{3} = [get(app.c31,'String'),'..',get(app.c32,'String')];
                    cal.CSV(3,:) = a12rc(get(app.c31,'String'));
                    cal.Range{4} = [get(app.c41,'String'),'..',get(app.c42,'String')];
                    cal.CSV(4,:) = a12rc(get(app.c41,'String'));
                    cal.Range{5} = [get(app.c51,'String'),'..',get(app.c52,'String')];
                    cal.CSV(5,:) = a12rc(get(app.c51,'String'));
            
                    cal.loadend          = a12rc(get(app.c12,'String'));
                    cal.voltend          = a12rc(get(app.c22,'String'));
            
                    outStruct.savePathcal = loadCSV(cal,outStruct.output_location);
                    outStruct.cal_create=1; %track if .cal file was created
                case '.cal'
                    outStruct.savePathcal = cal.Path;
            
                    if outStruct.input_save_FLAG==1 %Option to copy intput file to output location
                        [newLocation,~,~]=fileparts(outStruct.output_location); %new output location
                        try
                            [cal_path,cal_filename,ext]=fileparts(outStruct.savePathcal); %extract file information
                            if isempty(cal_path)==1 %if .cal file is in current directory
                                cal_path=fileparts(mfilename('fullpath'));
                            end
                            if strcmp(cal_path,newLocation)==0 %if .cal file is not already in output location
                                new_path=fullfile(newLocation,[cal_filename,ext]);
                                copyfile(outStruct.savePathcal,new_path);
                            end
                        catch
                            fprintf('\n UNABLE TO SAVE .cal FILE IN OUTPUT LOCATION. \n');
                        end
                    end
            end
            
            outStruct.valid = get(app.validate,'Value');
            if outStruct.valid == 1
                val.type = 'validate';
                val.Path = get(app.valPath,'String');
                [~,~,valext] = fileparts(val.Path);
                switch valext
                    case '.csv'
                        val.Range{1} = [get(app.v11,'String'),'..',get(app.v12,'String')];
                        val.CSV(1,:) = a12rc(get(app.v11,'String'));
                        val.Range{2} = [get(app.v21,'String'),'..',get(app.v22,'String')];
                        val.CSV(2,:) = a12rc(get(app.v21,'String'));
                        val.Range{3} = [get(app.v31,'String'),'..',get(app.v32,'String')];
                        val.CSV(3,:) = a12rc(get(app.v31,'String'));
                        val.Range{4} = [get(app.v41,'String'),'..',get(app.v42,'String')];
                        val.CSV(4,:) = a12rc(get(app.v41,'String'));
                        val.Range{5} = [get(app.v51,'String'),'..',get(app.v52,'String')];
                        val.CSV(5,:) = a12rc(get(app.v51,'String'));
                        outStruct.savePathval = loadCSV(val,outStruct.output_location);
                        outStruct.val_create=1; %track if .val file was created
            
                    case '.val'
                        outStruct.savePathval = val.Path;
            
                        if outStruct.input_save_FLAG==1 %Option to copy intput file to output location
                            try
                                [newLocation,~,~]=fileparts(outStruct.output_location); %new output location
                                [val_path,val_filename,ext]=fileparts(outStruct.savePathval); %extract file information
                                if isempty(val_path)==1 %if .val file is in current directory
                                    val_path=fileparts(mfilename('fullpath'));
                                end
                                if strcmp(val_path,newLocation)==0 %if .val file is not already in output location
                                    new_path=fullfile(newLocation,[val_filename,ext]);
                                    copyfile(outStruct.savePathval,new_path);
                                end
                            catch
                                fprintf('\n UNABLE TO SAVE .val FILE IN OUTPUT LOCATION. \n');
                            end
                        end
                end
            end
            
            outStruct.approx = get(app.approximate,'Value');
            if outStruct.approx == 1
                app.type = 'approximate';
                app.Path = get(app.appPath,'String');
                [~,~,appext] = fileparts(app.Path);
                switch appext
                    case '.csv'
                        app.Range{1} = [get(app.a11,'String'),'..',get(app.a12,'String')];
                        app.CSV(1,:) = a12rc(get(app.a11,'String'));
                        app.Range{2} = [get(app.a21,'String'),'..',get(app.a22,'String')];
                        app.CSV(2,:) = a12rc(get(app.a21,'String'));
                        app.Range{3} = [get(app.a31,'String'),'..',get(app.a32,'String')];
                        app.CSV(3,:) = a12rc(get(app.a31,'String'));
                        app.Range{4} = [get(app.a41,'String'),'..',get(app.a42,'String')];
                        app.CSV(4,:) = a12rc(get(app.a41,'String'));
                        outStruct.savePathapp = loadCSV(app,outStruct.output_location);
                        outStruct.app_create=1; %track if .app file was created
                    case '.app'
                        outStruct.savePathapp = app.Path;
            
                        if outStruct.input_save_FLAG==1 %Option to copy intput file to output location
                            try
                                [newLocation,~,~]=fileparts(outStruct.output_location); %new output location
                                [app_path,app_filename,ext]=fileparts(outStruct.savePathapp); %extract file information
                                if isempty(app_path)==1 %if .app file is in current directory
                                    app_path=fileparts(mfilename('fullpath'));
                                end
                                if strcmp(app_path,newLocation)==0 %if .app file is not already in output location
                                    new_path=fullfile(newLocation,[app_filename,ext]);
                                    copyfile(outStruct.savePathapp,new_path);
                                end
                            catch
                                fprintf('\n UNABLE TO SAVE .app FILE IN OUTPUT LOCATION. \n');
                            end
                        end
                end
            end
            
            %Save run settings
            if outStruct.input_save_FLAG==1 %Option to save run settings
                [savePath,~,~]=fileparts(outStruct.output_location); %new output location
                saveSettings(handles,'runSettings',savePath,outStruct.REPORT_NO);
            end
            
            outStruct.cancel = 0;
            
            app.output = outStruct;
            guidata(hObject, handles);
        end

        % Button pushed function: termSelectButton
        function termSelectButton_Callback(app, event)
            % hObject    handle to termSelectButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.runbutton,'Enable','off');
            set(app.cancelbutton,'Enable','off');
            termSelectOut=termSelect_GUI(app.termSelectButton.Tooltip);
            set(app.termSelectButton,'Tooltip',termSelectOut.termInclude);
            set(app.runbutton,'Enable','on');
            set(app.cancelbutton,'Enable','on');
        end

        % Button pushed function: valFind
        function valFind_Callback(app, event)
            % hObject    handle to valFind (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName, PathName] = uigetfile('*.csv;*.val');
            if FileName ~= 0
                [CurrentPath,~,~] = fileparts(mfilename('fullpath'));
                CurrentPath = [CurrentPath,'\'];
                if strcmp(CurrentPath,PathName)
                    FullPath = FileName;
                else
                    FullPath = [PathName,FileName];
                end
                set(app.valPath,'Value',FullPath)
            end
            valPath_Callback(app, event)
        end

        % Value changed function: valPath
        function valPath_Callback(app, event)
            % hObject    handle to valPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of valPath as text
            %        str2double(get(hObject,'String')) returns contents of valPath as a double
            [~,~,ext] = fileparts(app.valPath.Value);
            try
                if strcmp(ext,'.csv')
                    %set(app.valSave, 'Enable', 'on');
                    set(app.v11, 'Enable', 'on');
                    set(app.v12, 'Enable', 'on');
                    set(app.v21, 'Enable', 'on');
                    set(app.v22, 'Enable', 'on');
                    set(app.v31, 'Enable', 'on');
                    set(app.v32, 'Enable', 'on');
                    set(app.v41, 'Enable', 'on');
                    set(app.v42, 'Enable', 'on');
                    set(app.v51, 'Enable', 'on');
                    set(app.v52, 'Enable', 'on');
                else
                    %set(app.valSave, 'Enable', 'off');
                    if strcmp(ext,'.val')
                        load(app.valPath.Value, '-mat', 'val');
                        splitrange = split(val.Range,'..');
                    else
                        splitrange=cell(1,5,2);
                    end
            
                    set(app.v11, 'Enable', 'off', 'Value', splitrange{1,1,1});
                    set(app.v12, 'Enable', 'off', 'Value', splitrange{1,1,2});
                    set(app.v21, 'Enable', 'off', 'Value', splitrange{1,2,1});
                    set(app.v22, 'Enable', 'off', 'Value', splitrange{1,2,2});
                    set(app.v31, 'Enable', 'off', 'Value', splitrange{1,3,1});
                    set(app.v32, 'Enable', 'off', 'Value', splitrange{1,3,2});
                    set(app.v41, 'Enable', 'off', 'Value', splitrange{1,4,1});
                    set(app.v42, 'Enable', 'off', 'Value', splitrange{1,4,2});
                    set(app.v51, 'Enable', 'off', 'Value', splitrange{1,5,1});
                    set(app.v52, 'Enable', 'off', 'Value', splitrange{1,5,2});
                end
            catch
                disp('Problem occurred while reading Validation file');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AOX_GUI';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Outputs';
            app.disp_FLAGcheck = uicheckbox(app.uipanel1);
            app.disp_FLAGcheck.Text = 'Display Performance Parameters';
            app.hist_FLAGcheck = uicheckbox(app.uipanel1);
            app.hist_FLAGcheck.Text = 'Plot Histograms';
            app.print_FLAGcheck = uicheckbox(app.uipanel1);
            app.print_FLAGcheck.Text = 'Print Performance Parameter xlsx Files';
            app.res_FLAGcheck = uicheckbox(app.uipanel1);
            app.res_FLAGcheck.Text = 'Plot Residuals';
            app.corr_FLAGcheck = uicheckbox(app.uipanel1);
            app.corr_FLAGcheck.Text = 'Plot Correlations';
            app.rescorr_FLAGcheck = uicheckbox(app.uipanel1);
            app.rescorr_FLAGcheck.Text = 'Plot Residual Correlations';
            app.excel_FLAGcheck = uicheckbox(app.uipanel1);
            app.excel_FLAGcheck.Text = 'Print Load and Coefficient csv Files';
            app.BALFIT_Matrix_FLAGcheck = uicheckbox(app.uipanel1);
            app.BALFIT_Matrix_FLAGcheck.Text = 'Print BALFIT Matrix txt File';
            app.BALFIT_ANOVA_FLAGcheck = uicheckbox(app.uipanel1);
            app.BALFIT_ANOVA_FLAGcheck.Enable = 'off';
            app.BALFIT_ANOVA_FLAGcheck.Text = 'Print BALFIT ANOVA xlsx File';
            app.Rec_Model_FLAGcheck = uicheckbox(app.uipanel1);
            app.Rec_Model_FLAGcheck.Enable = 'off';
            app.Rec_Model_FLAGcheck.Text = 'Print Recommended Alg Model csv File';
            app.approx_and_PI_print = uicheckbox(app.uipanel1);
            app.approx_and_PI_print.Enable = 'off';
            app.approx_and_PI_print.Text = 'Print Load w/ Prediction Interval xlsx File';
            app.text57 = uilabel(app.uipanel1);
            app.text57.Text = 'Plot Outputs';
            app.text58 = uilabel(app.uipanel1);
            app.text58.Text = 'File Outputs';
            app.output_location = uieditfield(app.uipanel1, 'text');
            app.output_location_button = uibutton(app.uipanel1, 'push');
            app.output_location_button.ButtonPushedFcn = createCallbackFcn(app, @output_location_button_Callback, true);
            app.output_location_button.Text = 'Browse...';
            app.text59 = uilabel(app.uipanel1);
            app.text59.Text = 'File Output Location:';
            app.output_to_calib_FLAG = uicheckbox(app.uipanel1);
            app.output_to_calib_FLAG.ValueChangedFcn = createCallbackFcn(app, @output_to_calib_FLAG_Callback, true);
            app.output_to_calib_FLAG.Text = 'Output to Calibration File Location';
            app.subfolder_FLAG = uicheckbox(app.uipanel1);
            app.subfolder_FLAG.Text = 'Create Run Subfolder';
            app.calib_model_save_FLAG = uicheckbox(app.uipanel1);
            app.calib_model_save_FLAG.Text = 'Save Calibration Model .mat File';
            app.input_save_FLAG = uicheckbox(app.uipanel1);
            app.input_save_FLAG.Text = 'Save Input Data to Output Location';
            app.input_save_FLAG.Value = true;
            app.modelPanel = uibuttongroup(app.figure1);
            app.modelPanel.SelectionChangedFcn = createCallbackFcn(app, @modelPanel_SelectionChangeFcn, true);
            app.modelPanel.Title = 'Algebraic Model';
            app.truncated = uiradiobutton(app.modelPanel);
            app.truncated.Text = 'Truncated Eqns (Balance Type 1-A)';
            app.truncated.Value = true;
            app.linear = uiradiobutton(app.modelPanel);
            app.linear.Text = 'Linear Eqns';
            app.full = uiradiobutton(app.modelPanel);
            app.full.Text = 'Full Eqns (Balance Type 2-D)';
            app.RBF_text = uilabel(app.modelPanel);
            app.RBF_text.Enable = 'off';
            app.RBF_text.Text = 'Number of Basis Functions:';
            app.grbf = uicheckbox(app.modelPanel);
            app.grbf.ValueChangedFcn = createCallbackFcn(app, @grbf_Callback, true);
            app.grbf.Text = 'Include GRBF''s';
            app.text30 = uilabel(app.modelPanel);
            app.text30.Text = 'GRBF Addition';
            app.custom = uiradiobutton(app.modelPanel);
            app.custom.Text = 'Custom Eqn File';
            app.customPath = uieditfield(app.modelPanel, 'text');
            app.customPath.Enable = 'off';
            app.customFind = uibutton(app.modelPanel, 'push');
            app.customFind.ButtonPushedFcn = createCallbackFcn(app, @customFind_Callback, true);
            app.customFind.Enable = 'off';
            app.customFind.Text = 'Browse...';
            app.text55 = uilabel(app.modelPanel);
            app.text55.Text = 'Outliers';
            app.zeroed_FLAGcheck = uicheckbox(app.modelPanel);
            app.zeroed_FLAGcheck.Enable = 'off';
            app.zeroed_FLAGcheck.Text = 'Remove Outliers';
            app.outlier_FLAGcheck = uicheckbox(app.modelPanel);
            app.outlier_FLAGcheck.ValueChangedFcn = createCallbackFcn(app, @outlier_FLAGcheck_Callback, true);
            app.outlier_FLAGcheck.Text = 'Identify Possible Outliers';
            app.std_text = uilabel(app.modelPanel);
            app.std_text.Enable = 'off';
            app.std_text.Text = 'Num. of Std Devs:';
            app.numSTD = uieditfield(app.modelPanel, 'text');
            app.numSTD.Enable = 'off';
            app.numSTD.Value = '4';
            app.balanceType = uiradiobutton(app.modelPanel);
            app.balanceType.Text = 'Balance Type Eqns';
            app.balanceType_list = uidropdown(app.modelPanel);
            app.balanceType_list.Items = {'Type 1-A (F, F*F, F*G)', 'Type 1-B (F, F*F, F*G, F*F*F)', 'Type 1-C (F, F*G)', 'Tyep 1-D (F, F*F)', 'Type 2-A (F, |F|, F*F, F*G)', 'Type 2-B (F, |F|, F*F, F*|F|, F*G)', 'Type 2-C (F, |F|, F*F, F*|F|, F*G, |F*G|, F*|G|, |F|*G)', 'Type 2-D (Full) (F, |F|, F*F, F*|F|, F*G, |F*G|, F*|G|, |F|*G, F*F*F, |F*F*F|)', 'Type 2-E (F, |F|, F*|F|, F*G)', 'Type 2-F (F, |F|, F*G)'};
            app.balanceType_list.Enable = 'off';
            app.balanceType_list.Value = 'Type 1-A (F, F*F, F*G)';
            app.termSelect = uiradiobutton(app.modelPanel);
            app.termSelect.Text = 'Custom Term Selection';
            app.termSelectButton = uibutton(app.modelPanel, 'push');
            app.termSelectButton.ButtonPushedFcn = createCallbackFcn(app, @termSelectButton_Callback, true);
            app.termSelectButton.Enable = 'off';
            app.termSelectButton.Tooltip = 'Click to select terms';
            app.termSelectButton.Text = 'Select Terms';
            app.numBasisIn = uieditfield(app.modelPanel, 'text');
            app.numBasisIn.Enable = 'off';
            app.numBasisIn.Value = '10';
            app.selfTerm_pop = uidropdown(app.modelPanel);
            app.selfTerm_pop.Items = {'No Early Termination'};
            app.selfTerm_pop.Enable = 'off';
            app.selfTerm_pop.Tooltip = 'ANOVA or Validation Data Required for Self-Termination';
            app.selfTerm_pop.Value = 'No Early Termination';
            app.runbutton = uibutton(app.figure1, 'push');
            app.runbutton.ButtonPushedFcn = createCallbackFcn(app, @runbutton_Callback, true);
            app.runbutton.Text = 'Run';
            app.cancelbutton = uibutton(app.figure1, 'push');
            app.cancelbutton.ButtonPushedFcn = createCallbackFcn(app, @cancelbutton_Callback, true);
            app.cancelbutton.Text = 'Cancel';
            app.actionpanel = uibuttongroup(app.figure1);
            app.actionpanel.SelectionChangedFcn = createCallbackFcn(app, @actionpanel_SelectionChangeFcn, true);
            app.actionpanel.Title = 'Action';
            app.calibrate = uiradiobutton(app.actionpanel);
            app.calibrate.Text = 'Calibration Only';
            app.calibrate.Value = true;
            app.validate = uiradiobutton(app.actionpanel);
            app.validate.Text = 'Calibration + Validation';
            app.approximate = uiradiobutton(app.actionpanel);
            app.approximate.Text = 'Calibration + Approximation';
            app.calcsv = uipanel(app.figure1);
            app.calcsv.Title = 'Calibration';
            app.text5 = uilabel(app.calcsv);
            app.text5.Text = 'Load Capacities:';
            app.text6 = uilabel(app.calcsv);
            app.text6.Text = 'Natural Zeros:';
            app.text7 = uilabel(app.calcsv);
            app.text7.Text = 'Series 1 Column:';
            app.text8 = uilabel(app.calcsv);
            app.text8.Text = 'Load Array:';
            app.text9 = uilabel(app.calcsv);
            app.text9.Text = 'Voltage Array:';
            app.c11 = uieditfield(app.calcsv, 'text');
            app.c11.Enable = 'off';
            app.c12 = uieditfield(app.calcsv, 'text');
            app.c12.Enable = 'off';
            app.c21 = uieditfield(app.calcsv, 'text');
            app.c21.Enable = 'off';
            app.c22 = uieditfield(app.calcsv, 'text');
            app.c22.Enable = 'off';
            app.c31 = uieditfield(app.calcsv, 'text');
            app.c31.Enable = 'off';
            app.c32 = uieditfield(app.calcsv, 'text');
            app.c32.Enable = 'off';
            app.c41 = uieditfield(app.calcsv, 'text');
            app.c41.Enable = 'off';
            app.c42 = uieditfield(app.calcsv, 'text');
            app.c42.Enable = 'off';
            app.c51 = uieditfield(app.calcsv, 'text');
            app.c51.Enable = 'off';
            app.c52 = uieditfield(app.calcsv, 'text');
            app.c52.Enable = 'off';
            app.calstring = uilabel(app.calcsv);
            app.calstring.Text = 'Calibration Data:';
            app.calPath = uieditfield(app.calcsv, 'text');
            app.calPath.ValueChangedFcn = createCallbackFcn(app, @calPath_Callback, true);
            app.calPath.Enable = 'off';
            app.calFind = uibutton(app.calcsv, 'push');
            app.calFind.ButtonPushedFcn = createCallbackFcn(app, @calFind_Callback, true);
            app.calFind.Enable = 'off';
            app.calFind.Text = 'Browse...';
            app.text27 = uilabel(app.calcsv);
            app.text27.Text = 'csv Data File Range';
            app.valcsv = uipanel(app.figure1);
            app.valcsv.Title = 'Validation';
            app.text12 = uilabel(app.valcsv);
            app.text12.Text = 'Load Capacities:';
            app.text13 = uilabel(app.valcsv);
            app.text13.Text = 'Natural Zeros:';
            app.text14 = uilabel(app.valcsv);
            app.text14.Text = 'Series 1 Column:';
            app.text15 = uilabel(app.valcsv);
            app.text15.Text = 'Load Array:';
            app.text16 = uilabel(app.valcsv);
            app.text16.Text = 'Voltage Array:';
            app.v11 = uieditfield(app.valcsv, 'text');
            app.v11.Enable = 'off';
            app.v12 = uieditfield(app.valcsv, 'text');
            app.v12.Enable = 'off';
            app.v21 = uieditfield(app.valcsv, 'text');
            app.v21.Enable = 'off';
            app.v22 = uieditfield(app.valcsv, 'text');
            app.v22.Enable = 'off';
            app.v31 = uieditfield(app.valcsv, 'text');
            app.v31.Enable = 'off';
            app.v32 = uieditfield(app.valcsv, 'text');
            app.v32.Enable = 'off';
            app.v41 = uieditfield(app.valcsv, 'text');
            app.v41.Enable = 'off';
            app.v42 = uieditfield(app.valcsv, 'text');
            app.v42.Enable = 'off';
            app.v51 = uieditfield(app.valcsv, 'text');
            app.v51.Enable = 'off';
            app.v52 = uieditfield(app.valcsv, 'text');
            app.v52.Enable = 'off';
            app.valPath = uieditfield(app.valcsv, 'text');
            app.valPath.ValueChangedFcn = createCallbackFcn(app, @valPath_Callback, true);
            app.valPath.Enable = 'off';
            app.valFind = uibutton(app.valcsv, 'push');
            app.valFind.ButtonPushedFcn = createCallbackFcn(app, @valFind_Callback, true);
            app.valFind.Enable = 'off';
            app.valFind.Text = 'Browse...';
            app.text28 = uilabel(app.valcsv);
            app.text28.Text = 'csv Data File Range';
            app.valstring = uilabel(app.valcsv);
            app.valstring.Text = 'Validation Data:';
            app.ini_button = uibutton(app.figure1, 'push');
            app.ini_button.ButtonPushedFcn = createCallbackFcn(app, @ini_button_Callback, true);
            app.ini_button.Text = 'Set as Default...';
            app.text34 = uilabel(app.figure1);
            app.text34.Text = 'Experimental Aero-Physics Branch (Code AOX)';
            app.text35 = uilabel(app.figure1);
            app.text35.Text = 'AOX_BalCal';
            app.text37 = uilabel(app.figure1);
            app.text37.Text = 'Rice University';
            app.text38 = uilabel(app.figure1);
            app.text38.Text = 'Department of Mechanical Engineering';
            app.text39 = uilabel(app.figure1);
            app.text39.Text = 'Dr. Andrew Meade';
            app.text40 = uilabel(app.figure1);
            app.text40.Text = 'Javier Villarreal';
            app.text41 = uilabel(app.figure1);
            app.text41.Text = 'Ali Mokhtarzadeh';
            app.text42 = uilabel(app.figure1);
            app.text42.Text = 'Authors:';
            app.text48 = uilabel(app.figure1);
            app.text48.Text = 'Experimental Aero-Physics Branch (Code AOX)';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Uncertainty Options';
            app.anova_FLAGcheck = uicheckbox(app.uibuttongroup1);
            app.anova_FLAGcheck.ValueChangedFcn = createCallbackFcn(app, @anova_FLAGcheck_Callback, true);
            app.anova_FLAGcheck.Text = 'Perform Analysis of Variance (ANOVA)';
            app.anova_pct_text = uilabel(app.uibuttongroup1);
            app.anova_pct_text.Enable = 'off';
            app.anova_pct_text.Text = 'ANOVA % Confidence';
            app.anova_pct = uieditfield(app.uibuttongroup1, 'text');
            app.anova_pct.Enable = 'off';
            app.anova_pct.Value = '95';
            app.stableRec_FLAGcheck = uicheckbox(app.uibuttongroup1);
            app.stableRec_FLAGcheck.Enable = 'off';
            app.stableRec_FLAGcheck.Text = 'Iterate for Stable Recommended Eqn';
            app.text52 = uilabel(app.figure1);
            app.text52.Text = 'John Potthoff';
            app.appcsv = uipanel(app.figure1);
            app.appcsv.Title = 'Approximation';
            app.text47 = uilabel(app.appcsv);
            app.text47.Text = 'Voltage Array:';
            app.text46 = uilabel(app.appcsv);
            app.text46.Text = 'Series 1 Column:';
            app.a42 = uieditfield(app.appcsv, 'text');
            app.a42.Enable = 'off';
            app.a41 = uieditfield(app.appcsv, 'text');
            app.a41.Enable = 'off';
            app.a32 = uieditfield(app.appcsv, 'text');
            app.a32.Enable = 'off';
            app.a31 = uieditfield(app.appcsv, 'text');
            app.a31.Enable = 'off';
            app.a22 = uieditfield(app.appcsv, 'text');
            app.a22.Enable = 'off';
            app.a21 = uieditfield(app.appcsv, 'text');
            app.a21.Enable = 'off';
            app.text43 = uilabel(app.appcsv);
            app.text43.Text = 'Natural Zeros:';
            app.text29 = uilabel(app.appcsv);
            app.text29.Text = 'csv Data File Range';
            app.appFind = uibutton(app.appcsv, 'push');
            app.appFind.ButtonPushedFcn = createCallbackFcn(app, @appFind_Callback, true);
            app.appFind.Enable = 'off';
            app.appFind.Text = 'Browse...';
            app.appPath = uieditfield(app.appcsv, 'text');
            app.appPath.ValueChangedFcn = createCallbackFcn(app, @appPath_Callback, true);
            app.appPath.Enable = 'off';
            app.text23 = uilabel(app.appcsv);
            app.text23.Text = 'Approximation Data:';
            app.a12 = uieditfield(app.appcsv, 'text');
            app.a12.Enable = 'off';
            app.a11 = uieditfield(app.appcsv, 'text');
            app.a11.Enable = 'off';
            app.text22 = uilabel(app.appcsv);
            app.text22.Text = 'Load Capacities:';
            app.load_settings_button = uibutton(app.figure1, 'push');
            app.load_settings_button.ButtonPushedFcn = createCallbackFcn(app, @load_settings_button_Callback, true);
            app.load_settings_button.Text = 'Load Settings';
            app.NASAImage = uiimage(app.figure1);
            app.NASAImage.ImageSource = 'nasa.png';
            app.RiceImage = uiimage(app.figure1);
            app.RiceImage.ImageSource = 'rice.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOX_GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @AOX_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end